classdef birdClassificationApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Img                   matlab.ui.control.Label
        Path                  matlab.ui.control.Label
        Image                 matlab.ui.control.Image
        Label                 matlab.ui.control.Label
        ClasssifyImageButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        model % Description
        classes
    end
    
    methods (Access = private)
        
        function convertedImg = convertInvalidFiles(~, filePath, method)
            arguments
                ~
                filePath (1,:) char {}
                method (1,:) char {mustBeMember(method, {'python', 'imagemagic'})} = 'python'
            end
            
            [directory, fileName, ~] = fileparts(filePath);
            convertedImg = fullfile(directory, [fileName, '_converted.jpg']);

            switch method
                case 'python'

                    try
                        py.importlib.import_module("PIL.Image");
                        py.importlib.import_module("pillow_avif");

                        img = py.PIL.Image.open(char(filePath));
                        img = img.convert('RGB');
                        img.save(char(convertedImg));

                    catch ME
                        error("Python conversion failed. Make sure PIL is installed. Error: %s", ME.message);
                    end

                % case 'imagemagic'
                % 
                %     case 'imagemagick'
                %         cmd = sprintf('magick "%s" "%s"', filePath, convertedImg);
                %         status = system(cmd);
                %         if status ~= 0
                %             error('ImageMagick conversion failed. Ensure `magick` is installed and in PATH.');
                %         end
                    
                
            end

            if ~isfile(convertedImg)
                error('Conversion failed: output file was not created.');
            end
        end
        
        function fileType = getActualFileType(~, filePath)
            py.importlib.import_module('filetype');
            kind = py.filetype.guess(char(filePath));
        
            if isempty(kind)
                fileType = 'unknown';
            else
                fileType = string(kind.extension); % e.g., 'webp', 'png', 'jpeg'
            end
        end


        function ext = getFileExtension(~, filePath)
            [~, ~, ext] = fileparts(filePath);
            ext = lower(strrep(ext, '.', ''));  % Remove dot and lowercase
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            trainedModel = load("trainedBirdModel.mat");
            app.model = trainedModel.trainedNet;
            app.classes = app.model.Layers(end).Classes;
            
        end

        % Button pushed function: ClasssifyImageButton
        function ClasssifyImageButtonPushed(app, event)
            [img, filePath] = uigetfile({'*.jpg';'*.png';'*.jpeg';'*.*'}, 'Select bird image');

            validFileTypes = ["jpg", "png", "jpeg"];

            if isequal(img, 0)
                return;
            end

            fullPath = fullfile(filePath, img);

            
            try
                bird = imread(fullPath);
            catch
                try
                    % Convert unsupported image to JPG
                    warning('Attempting conversion...');
                    convertedPath = fullPath;

                    fileType = getActualFileType(app, fullPath);


                    if any(fileType ~= validFileTypes)
                        
                        [directory, name, ~] = fileparts(fullPath);
                        fixedPath = fullfile(directory, [name, '.', char(fileType)]);
                        app.Path.Text = fullPath;
                        app.Img.Text = fixedPath;
                        app.Label.Text = fileType;
                        
                        if ~strcmpi(fileType, getFileExtension(app, fullPath))
                            try
                                movefile(fullPath, fixedPath, 'f');  % force overwrite if exists
                            catch ME
                                error("Failed to rename file: %s", ME.message);
                            end
                        else
                            fixedPath = fullPath;  % already correct
                        end

                        convertedPath = convertInvalidFiles(app, fixedPath, 'python');
                    end

                    bird = imread(convertedPath);
                catch ME
                    uialert(app.UIFigure, ['Failed to read or convert image: ' ME.message], 'Image Error');
                    return;
                end
            end
        
            bird = imresize(bird, [224 224]);
            
            % checking whether the image is 3d rgb or grayscale
            if size(bird, 3) == 1
            
                %if grayscale, turn it to rgb by copying the single layer 3 times
                bird = repmat(bird, 1, 1, 3);
            end


            %imshow(bird, 'Parent', app.UIAxes);
            app.Image.ImageSource = bird;
            
            prediction = classify(app.model, bird);
            
            app.Label.Text = "Predicted Bird Class: " + string(prediction);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ClasssifyImageButton = uibutton(app.UIFigure, 'push');
            app.ClasssifyImageButton.ButtonPushedFcn = createCallbackFcn(app, @ClasssifyImageButtonPushed, true);
            app.ClasssifyImageButton.Text = 'Classsify Image';
            app.Label = uilabel(app.UIFigure);
            app.Image = uiimage(app.UIFigure);
            app.Path = uilabel(app.UIFigure);
            app.Path.Text = 'Path';
            app.Img = uilabel(app.UIFigure);
            app.Img.Text = 'Img';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = birdClassificationApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end